classdef SYNTH < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        OscillatorFrequencyEditField  matlab.ui.control.EditField
        EnterFrequencyForOscillatorEditFieldLabel  matlab.ui.control.Label
        SawtoothAmpSlider             matlab.ui.control.Slider
        AmplitudeSlider_3Label        matlab.ui.control.Label
        TuneSlider                    matlab.ui.control.Slider
        TuneSliderLabel               matlab.ui.control.Label
        SquareOscWidthSlider          matlab.ui.control.Slider
        WidthSliderLabel              matlab.ui.control.Label
        SquareOscAmpSlider            matlab.ui.control.Slider
        AmplitudeSliderLabel          matlab.ui.control.Label
        EnvelopeAmountSlider          matlab.ui.control.Slider
        EnvelopeAmountSliderLabel     matlab.ui.control.Label
        LFOAmountSlider               matlab.ui.control.Slider
        LFOAmountSliderLabel          matlab.ui.control.Label
        SquareOscAxes                 matlab.ui.control.UIAxes
        SawtoothOscAxes               matlab.ui.control.UIAxes
        CenterPanel                   matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        VolumeSlider                  matlab.ui.control.Slider
        VolumeSliderLabel             matlab.ui.control.Label
        FileNameEditField             matlab.ui.control.EditField
        ImportFileButton              matlab.ui.control.Button
        MediaControlLabel             matlab.ui.control.Label
        Button                        matlab.ui.control.Button
        Button_2                      matlab.ui.control.Button
        FreqAxes                      matlab.ui.control.UIAxes
        TimeAxes                      matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % instance vars:
    % originalWaveFileObj - object holding all information in original wav
    % currWaveData - object holding all information in new wave file
    % squareOscillator - time domain array storing square oscillator
    % sawtoothOscillator - time domain array storing sawtooth oscillator
    % 
    % squareWidth - parameter to control duty cycle of square oscillator
    % squareAmplitude - amplitude of square oscillator
    %
    % sawtoothAmplitude - amplitude of sawtooth oscillator

    properties (Access = private)

        originalWaveFileObj
        currWaveFileObj
        squareOscillator
        sawtoothOscillator

        waveAmplitude

        squareAmplitude
        squareWidth

        sawtoothAmplitude

        oscillatorFrequency
    end
    
    % All helper methods to handle gui interaction
    methods (Access = private)

        %{
            Initializes the .wav file object to be used

            @param filePath - the path of the file read in
        %}
        function loadWav(app, filePath)
        
            app.originalWaveFileObj = WaveFile(filePath);
            app.currWaveFileObj = app.originalWaveFileObj;
        end
        
        %{
            Initializes the square oscillator
        %}
        function initializeSquare(app, amplitude, freq, width, duration, fs)

            app.squareOscillator = generateSquareOscillator(amplitude, freq, width, duration, fs);
            app.squareOscillator = app.squareOscillator + 1;
        end

        %{
            Initializes the square oscillator
        %}
        function initializeSawtooth(app, amplitude, freq,  duration, fs)
            
            app.sawtoothOscillator = generateSawtoothOscillator(amplitude, freq, duration, fs);
            app.sawtoothOscillator = app.sawtoothOscillator + 1;
        end

        %{
            Plays sound of .wav file, from the beginning
        %}
        function playAudio(app)
         
            playSound(app.currWaveFileObj);    
        end

        %{
            Stops playing sound (if currently playing)
        %}
        function stopAudio(app)

            stopSound();   
        end

        %{
            Modulates wave with both oscillators
        %}
        function modulateWithOscillators(app)

            app.currWaveFileObj.timeData = modulation(app.originalWaveFileObj.timeData, app.squareOscillator);
            app.currWaveFileObj.timeData = modulation(app.currWaveFileObj.timeData, app.sawtoothOscillator);
        end

        %{
            Plots the two graphs of our data.
        %}
        function loadPlot(app)
            
            timeAxesHandle = app.TimeAxes;
            freqAxesHandle = app.FreqAxes;

            plot(timeAxesHandle, app.currWaveFileObj.timeData);
            ylim(timeAxesHandle, [-1, 1]);
            plot(freqAxesHandle, abs(app.currWaveFileObj.freqData));
        end

        %{
            Plots oscillator type. Currently only plotting 2 periods

            @param axesHandle - axes reference
            @param audioData - data of audio (time domain)
        %}
        function loadOscillator(app, axesHandle, fs, audioData)
            
            numPeriodsToPlot = 2;
            plot(axesHandle, audioData(1:fs * numPeriodsToPlot / app.oscillatorFrequency)); 
            
            ylim(axesHandle, [-1, 3]);
            yline(axesHandle, 1, 'k--');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.squareWidth = 0;
            app.squareAmplitude = 1;

            app.sawtoothAmplitude = 1;
            
            app.oscillatorFrequency = 440;

            initializeSquare(app, app.squareAmplitude, app.oscillatorFrequency, app.squareWidth, 2, 44100);
            initializeSawtooth(app, app.sawtoothAmplitude/2, app.oscillatorFrequency, 2, 44100);

            loadOscillator(app, app.SquareOscAxes, 44100, app.squareOscillator);
            loadOscillator(app, app.SawtoothOscAxes, 44100, app.sawtoothOscillator);
        end

        % Button pushed function: ImportFileButton
        function ImportFileButtonPushed2(app, event)
            % read in data. ref:
            % https://www.mathworks.com/help/matlab/ref/uigetfile.html
            % https://www.mathworks.com/help/matlab/ref/fullfile.html
            
            [filename, path] = uigetfile('*.wav');
            app.FileNameEditField.Value = filename;
            app.waveAmplitude = 50;

            % arbitrary value for now
            

            loadWav(app, fullfile(path, filename));
            initializeSquare(app, app.squareAmplitude, app.oscillatorFrequency, app.squareWidth, length(app.currWaveFileObj.timeData)/app.currWaveFileObj.fs, app.currWaveFileObj.fs); 
            initializeSawtooth(app, app.sawtoothAmplitude/2, app.oscillatorFrequency, length(app.currWaveFileObj.timeData)/app.currWaveFileObj.fs, app.currWaveFileObj.fs);

            modulateWithOscillators(app);
            % load the plots whenever new file is uploaded
            loadPlot(app);
            loadOscillator(app, app.SquareOscAxes, app.currWaveFileObj.fs, app.squareOscillator);
            loadOscillator(app, app.SawtoothOscAxes, app.currWaveFileObj.fs, app.sawtoothOscillator);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            playAudio(app);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            stopAudio(app);
        end

        % Value changed function: VolumeSlider
        function VolumeSliderValueChanged2(app, event)
            app.waveAmplitude = app.VolumeSlider.Value;

            app.currWaveFileObj = changeAmplitude(app.originalWaveFileObj, app.waveAmplitude/50);
            loadPlot(app);
        end

        % Value changed function: SquareOscAmpSlider
        function SquareOscAmpSliderValueChanged(app, event)
            app.squareAmplitude = app.SquareOscAmpSlider.Value / 50;

            initializeSquare(app, app.squareAmplitude, app.oscillatorFrequency, app.squareWidth, length(app.currWaveFileObj.timeData)/app.currWaveFileObj.fs, app.currWaveFileObj.fs);
            
            modulateWithOscillators(app);
            loadOscillator(app, app.SquareOscAxes, app.currWaveFileObj.fs, app.squareOscillator);
            loadPlot(app);
        end

        % Value changed function: SquareOscWidthSlider
        function SquareOscWidthSliderValueChanged(app, event)
            app.squareWidth = app.SquareOscWidthSlider.Value;

            initializeSquare(app, app.squareAmplitude, app.oscillatorFrequency, app.squareWidth, length(app.currWaveFileObj.timeData)/app.currWaveFileObj.fs, app.currWaveFileObj.fs);
            
            modulateWithOscillators(app);
            loadOscillator(app, app.SquareOscAxes, app.currWaveFileObj.fs, app.squareOscillator);
            loadPlot(app);
        end

        % Value changed function: SawtoothAmpSlider
        function SawtoothAmpSliderValueChanged(app, event)
            app.sawtoothAmplitude = app.SawtoothAmpSlider.Value/(2 * 50);
            
            initializeSawtooth(app, app.sawtoothAmplitude, app.oscillatorFrequency, length(app.currWaveFileObj.timeData)/app.currWaveFileObj.fs, app.currWaveFileObj.fs); 
            
            modulateWithOscillators(app);
            loadOscillator(app, app.SawtoothOscAxes, app.currWaveFileObj.fs, app.sawtoothOscillator);
            loadPlot(app);
        end

        % Value changed function: OscillatorFrequencyEditField
        function OscillatorFrequencyEditFieldValueChanged(app, event)
            app.oscillatorFrequency = str2double(app.OscillatorFrequencyEditField.Value);

            initializeSquare(app, app.squareAmplitude, app.oscillatorFrequency, app.squareWidth, length(app.currWaveFileObj.timeData)/app.currWaveFileObj.fs, app.currWaveFileObj.fs); 
            initializeSawtooth(app, app.sawtoothAmplitude/2, app.oscillatorFrequency, length(app.currWaveFileObj.timeData)/app.currWaveFileObj.fs, app.currWaveFileObj.fs);
            
            modulateWithOscillators(app);
            loadOscillator(app, app.SquareOscAxes, app.currWaveFileObj.fs, app.squareOscillator);
            loadPlot(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {676, 676, 676};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {676, 676};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {258, '1x', 231};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.SawtoothOscAxes = uiaxes(app.GridLayout2);
            title(app.SawtoothOscAxes, 'Sawtooth Oscillator (2 Periods)')
            app.SawtoothOscAxes.XTick = [];
            app.SawtoothOscAxes.YTick = [];
            app.SquareOscAxes = uiaxes(app.GridLayout2);
            title(app.SquareOscAxes, 'Square Oscillator (2 Periods)')
            app.SquareOscAxes.XTick = [];
            app.SquareOscAxes.YTick = [];
            app.LFOAmountSliderLabel = uilabel(app.GridLayout2);
            app.LFOAmountSliderLabel.Text = 'LFO Amount';
            app.LFOAmountSlider = uislider(app.GridLayout2);
            app.LFOAmountSlider.Value = 50;
            app.EnvelopeAmountSliderLabel = uilabel(app.GridLayout2);
            app.EnvelopeAmountSliderLabel.Text = 'Envelope Amount';
            app.EnvelopeAmountSlider = uislider(app.GridLayout2);
            app.EnvelopeAmountSlider.Value = 50;
            app.AmplitudeSliderLabel = uilabel(app.GridLayout2);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.SquareOscAmpSlider = uislider(app.GridLayout2);
            app.SquareOscAmpSlider.ValueChangedFcn = createCallbackFcn(app, @SquareOscAmpSliderValueChanged, true);
            app.SquareOscAmpSlider.Value = 50;
            app.WidthSliderLabel = uilabel(app.GridLayout2);
            app.WidthSliderLabel.Text = 'Width';
            app.SquareOscWidthSlider = uislider(app.GridLayout2);
            app.SquareOscWidthSlider.Limits = [-100 100];
            app.SquareOscWidthSlider.MajorTicks = [-100 -50 0 50 100];
            app.SquareOscWidthSlider.MajorTickLabels = {'-100', '-50', '0', '50', '100'};
            app.SquareOscWidthSlider.ValueChangedFcn = createCallbackFcn(app, @SquareOscWidthSliderValueChanged, true);
            app.SquareOscWidthSlider.MinorTicks = [];
            app.TuneSliderLabel = uilabel(app.GridLayout2);
            app.TuneSliderLabel.Text = 'Tune';
            app.TuneSlider = uislider(app.GridLayout2);
            app.TuneSlider.Value = 50;
            app.AmplitudeSlider_3Label = uilabel(app.GridLayout2);
            app.AmplitudeSlider_3Label.Text = 'Amplitude';
            app.SawtoothAmpSlider = uislider(app.GridLayout2);
            app.SawtoothAmpSlider.ValueChangedFcn = createCallbackFcn(app, @SawtoothAmpSliderValueChanged, true);
            app.SawtoothAmpSlider.Value = 50;
            app.EnterFrequencyForOscillatorEditFieldLabel = uilabel(app.GridLayout2);
            app.EnterFrequencyForOscillatorEditFieldLabel.Text = 'Enter Frequency For Oscillator';
            app.OscillatorFrequencyEditField = uieditfield(app.GridLayout2, 'text');
            app.OscillatorFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @OscillatorFrequencyEditFieldValueChanged, true);
            app.OscillatorFrequencyEditField.Value = '440';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.CenterPanel);
            app.TimeAxes = uiaxes(app.GridLayout3);
            title(app.TimeAxes, 'Audio Waveform')
            xlabel(app.TimeAxes, 'Time')
            ylabel(app.TimeAxes, 'Amplitude')
            zlabel(app.TimeAxes, 'Z')
            app.TimeAxes.XTick = [];
            app.TimeAxes.XTickLabel = '';
            app.TimeAxes.YTick = [];
            app.TimeAxes.YTickLabel = '';
            app.TimeAxes.ZTick = [];
            app.FreqAxes = uiaxes(app.GridLayout3);
            title(app.FreqAxes, 'Frequency Waveform')
            xlabel(app.FreqAxes, 'Angular Frequency')
            ylabel(app.FreqAxes, 'Amplitude')
            zlabel(app.FreqAxes, 'Z')
            app.FreqAxes.XTick = [];
            app.FreqAxes.XTickLabel = '';
            app.FreqAxes.YTick = [];
            app.FreqAxes.YTickLabel = '';
            app.FreqAxes.ZTick = [];
            app.Button_2 = uibutton(app.GridLayout3, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '■';
            app.Button = uibutton(app.GridLayout3, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '►';
            app.MediaControlLabel = uilabel(app.GridLayout3);
            app.MediaControlLabel.Text = 'Media Control';
            app.ImportFileButton = uibutton(app.GridLayout3, 'push');
            app.ImportFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportFileButtonPushed2, true);
            app.ImportFileButton.Text = 'Import File';
            app.FileNameEditField = uieditfield(app.GridLayout3, 'text');
            app.FileNameEditField.Value = 'File Name';
            app.VolumeSliderLabel = uilabel(app.GridLayout3);
            app.VolumeSliderLabel.Text = 'Volume';
            app.VolumeSlider = uislider(app.GridLayout3);
            app.VolumeSlider.ValueChangedFcn = createCallbackFcn(app, @VolumeSliderValueChanged2, true);
            app.VolumeSlider.Value = 50;
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SYNTH

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end